classdef RecreaText < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        ObjekteauswaehlenButton  matlab.ui.control.Button
        GOButton                 matlab.ui.control.Button
        TextauswaehlenButton     matlab.ui.control.Button
        UIAxes                   matlab.ui.control.UIAxes
        UIAxes_2                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        PictureObject % chosen object picture from imread
        PictureText % chosen text picture from imread
        PicNameObj % path of the chosen object picture
        PicNameText % path of the chosen text picture
    end
    
    methods (Access = private)
        
%         function results = main(app)
%            % app.Picture = BinaryImage.imageToBinary(app.Picture);
%            % app.Picture = EdgeDetector.cannyFilter(app.Picture);
%             subplot(1,2);
%             imshow(app.PictureObject, 'Parent', app.UIAxes);
%             imshow(app.PictureText, 'Parent', app.UIAxes_2);
%             results = image;
%         end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ObjekteauswaehlenButton
        function ObjekteauswaehlenButtonPushed(app, event)
            [app.PicNameObj, ~] = uigetfile(fullfile(pwd, '../assets', '*.*'),'select objects');
            app.PictureObject = imread(['../assets/' app.PicNameObj]);
            imshow(app.PictureObject, 'Parent', app.UIAxes);
        end

        % Button pushed function: GOButton
        function GOButtonPushed(app, event)
            main.mainFunc(app.PictureObject, app.PictureText);
        end

        % Button pushed function: TextauswaehlenButton
        function TextauswaehlenButtonPushed(app, event)
            [app.PicNameText, ~] = uigetfile(fullfile(pwd, '../assets','*.*'),'select text');
            app.PictureText = imread(['../assets/' app.PicNameText]);
            imshow(app.PictureText, 'Parent', app.UIAxes_2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ObjekteauswaehlenButton = uibutton(app.UIFigure, 'push');
            app.ObjekteauswaehlenButton.ButtonPushedFcn = createCallbackFcn(app, @ObjekteauswaehlenButtonPushed, true);
            app.ObjekteauswaehlenButton.Text = 'Objekte auswaehlen';
            app.GOButton = uibutton(app.UIFigure, 'push');
            app.GOButton.ButtonPushedFcn = createCallbackFcn(app, @GOButtonPushed, true);
            app.GOButton.Text = 'GO';
            app.TextauswaehlenButton = uibutton(app.UIFigure, 'push');
            app.TextauswaehlenButton.ButtonPushedFcn = createCallbackFcn(app, @TextauswaehlenButtonPushed, true);
            app.TextauswaehlenButton.Text = 'Text auswaehlen';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes_2 = uiaxes(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RecreaText

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end